classdef ButtonPanelApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Floor3Panel           matlab.ui.container.Panel
        F3_1                  matlab.ui.container.Panel
        F3_1_DownButton       matlab.ui.control.Button
        F3_1_EditField        matlab.ui.control.NumericEditField
        doorEditField_3Label  matlab.ui.control.Label
        F3_1_doorEditField    matlab.ui.control.EditField
        Floor2Panel           matlab.ui.container.Panel
        F2_1                  matlab.ui.container.Panel
        F2_1_DownButton       matlab.ui.control.Button
        F2_1_EditField        matlab.ui.control.NumericEditField
        F2_1_UpButton         matlab.ui.control.Button
        doorEditField_4Label  matlab.ui.control.Label
        F2_1_doorEditField    matlab.ui.control.EditField
        Floor1Panel           matlab.ui.container.Panel
        F1_1                  matlab.ui.container.Panel
        F1_1_UpButton         matlab.ui.control.Button
        F1_1_EditField        matlab.ui.control.NumericEditField
        doorEditField_5Label  matlab.ui.control.Label
        F1_1_doorEditField    matlab.ui.control.EditField
    end

    
    properties (Access = public)
        elevator1; % elevator#1
        elevator2; % elevator#1
        button2;   % buttonpanel#2
        eleUI1;
        eleUI2;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        
            
            app.F1_1_doorEditField.Value = 'close';
            app.F2_1_doorEditField.Value = 'close';
            app.F3_1_doorEditField.Value = 'close';

            
            app.F1_1_EditField.Value = 1;
            app.F2_1_EditField.Value = 1;
            app.F3_1_EditField.Value = 1;
        end

        % Button pushed function: F1_1_UpButton
        function F1_1_UpButtonPushed(app, event)
            app.F1_1_UpButton.BackgroundColor = [0.39,0.83,0.07];
            app.button2.F1_2_UpButton.BackgroundColor = [0.39,0.83,0.07];
            if app.elevator1.state == 'r'
                if app.elevator1.level == 1
                    app.elevator1.openDoor(11);
                else
                    app.elevator1.state = 'w';
                    app.elevator1.direction = 'd';
                    app.eleUI1.Lamp.Color = [0,1,0];
                    app.elevator1.ButtonFor1(1,11);
                    app.elevator1.direction = 's';
                    app.eleUI1.Lamp.Color = [0,0,0];
                    app.elevator1.state = 'r';
                end
                app.F1_1_UpButton.BackgroundColor = [0.96,0.96,0.96];
                app.button2.F1_2_UpButton.BackgroundColor = [0.96,0.96,0.96];
            
            elseif app.elevator1.state ~= 'w' && app.elevator2.state == 'r'
                if app.elevator2.level == 1
                    app.elevator2.openDoor(12);
                else
                   app.elevator2.state = 'w';
                   app.elevator2.direction = 'd';
                   app.eleUI2.Lamp.Color = [0,1,0];
                   app.elevator2.ButtonFor2(1,12);
                   app.elevator2.direction = 's';
                   app.eleUI2.Lamp.Color = [0,0,0];
                   app.elevator2.state = 'r';
                end
                app.F1_1_UpButton.BackgroundColor = [0.96,0.96,0.96];
                app.button2.F1_2_UpButton.BackgroundColor = [0.96,0.96,0.96];
            end

        end

        % Button pushed function: F2_1_UpButton
        function F2_1_UpButtonPushed(app, event)
            app.F2_1_UpButton.BackgroundColor = [0.39,0.83,0.07];
            app.button2.F2_2_UpButton.BackgroundColor = [0.39,0.83,0.07];
            
            if app.elevator1.state == 'r'
                if app.elevator1.level == 2
                    app.elevator1.openDoor(21);
                else
                    app.elevator1.state = 'w';
                    if app.elevator1.level > 2    
                        app.elevator1.direction = 'd';
                        app.eleUI1.Lamp.Color = [0.00,1.00,0.00];
                    else
                        app.elevator1.direction = 'u';
                        app.eleUI1.Lamp.Color = [1.00,0.00,0.00];
                    end
                    app.elevator1.ButtonFor1(2,21);
                    app.elevator1.direction = 's';
                    app.eleUI1.Lamp.Color = [0.00,0.00,0.00];
                    app.elevator1.state = 'r';
                end
                app.F2_1_UpButton.BackgroundColor = [0.96,0.96,0.96];
                app.button2.F2_2_UpButton.BackgroundColor = [0.96,0.96,0.96];
            
            elseif app.elevator1.state ~= 'w' && app.elevator2.state == 'r'
                if app.elevator2.level == 2
                    app.elevator2.openDoor(22);
                else
                   app.elevator2.state = 'w';
                    if app.elevator2.level > 2    
                        app.elevator2.direction = 'd';
                        app.eleUI2.Lamp.Color = [0.00,1.00,0.00];
                    else
                        app.elevator2.direction = 'u';
                        app.eleUI2.Lamp.Color = [1.00,0.00,0.00];
                    end
                   app.elevator2.ButtonFor2(2,22);
                   app.elevator2.direction = 's';
                   app.eleUI2.Lamp.Color = [0.00,0.00,0.00];
                   app.elevator2.state = 'r';
                end
                app.F2_1_UpButton.BackgroundColor = [0.96,0.96,0.96];
                app.button2.F2_2_UpButton.BackgroundColor = [0.96,0.96,0.96];
            end
        end

        % Button pushed function: F2_1_DownButton
        function F2_1_DownButtonPushed(app, event)
            app.F2_1_DownButton.BackgroundColor = [0.39,0.83,0.07];
            app.button2.F2_2_DownButton.BackgroundColor = [0.39,0.83,0.07];
            if app.elevator1.state == 'r'
                if app.elevator1.level == 2
                    app.elevator1.openDoor(21);
                else
                    app.elevator1.state = 'w';
                    if app.elevator1.level > 2    
                        app.elevator1.direction = 'd';
                        app.eleUI1.Lamp.Color = [0.00,1.00,0.00];
                    else
                        app.elevator1.direction = 'u';
                        app.eleUI1.Lamp.Color = [1.00,0.00,0.00];
                    end
                    app.elevator1.ButtonFor1(2,21);
                    app.elevator1.direction = 's';
                    app.eleUI1.Lamp.Color = [0.00,0.00,0.00];
                    app.elevator1.state = 'r';
                end
                app.F2_1_DownButton.BackgroundColor = [0.96,0.96,0.96];
                app.button2.F2_2_DownButton.BackgroundColor = [0.96,0.96,0.96];
            
            elseif app.elevator1.state ~= 'w' && app.elevator2.state == 'r'
                if app.elevator2.level == 2
                    app.elevator2.openDoor(22);
                else
                   app.elevator2.state = 'w';
                    if app.elevator2.level > 2    
                        app.elevator2.direction = 'd';
                        app.eleUI2.Lamp.Color = [0.00,1.00,0.00];
                    else
                        app.elevator2.direction = 'u';
                        app.eleUI2.Lamp.Color = [1.00,0.00,0.00];
                    end
                   app.elevator2.ButtonFor2(2,22);
                   app.elevator2.direction = 's';
                   app.eleUI2.Lamp.Color = [0.00,0.00,0.00];
                   app.elevator2.state = 'r';
                end
                app.F2_1_DownButton.BackgroundColor = [0.96,0.96,0.96];
                app.button2.F2_2_DownButton.BackgroundColor = [0.96,0.96,0.96];
            end
        end

        % Button pushed function: F3_1_DownButton
        function F3_1_DownButtonPushed(app, event)
            app.F3_1_DownButton.BackgroundColor = [0.39,0.83,0.07];
            app.button2.F3_2_DownButton.BackgroundColor = [0.39,0.83,0.07];
            if app.elevator1.state == 'r'
                if app.elevator1.level == 3
                    app.elevator1.openDoor(31);
                else
                    app.elevator1.state = 'w';
                    app.elevator1.direction = 'u';
                    app.eleUI1.Lamp.Color = [1.00,0.00,0.00];
                    app.elevator1.ButtonFor1(3,31);
                    app.elevator1.direction = 's';
                    app.eleUI1.Lamp.Color = [0.00,0.00,0.00];
                    app.elevator1.state = 'r';
                end
                app.F3_1_DownButton.BackgroundColor = [0.96,0.96,0.96];
                app.button2.F3_2_DownButton.BackgroundColor = [0.96,0.96,0.96];
            
            elseif app.elevator1.state ~= 'w' && app.elevator2.state == 'r'
                if app.elevator2.level == 3
                    app.elevator2.openDoor(32);
                else
                   app.elevator2.state = 'w';
                   app.elevator2.direction = 'u';
                   app.eleUI2.Lamp.Color = [1.00,0.00,0.00];
                   app.elevator2.ButtonFor2(3,32);
                   app.elevator2.direction = 's';
                   app.eleUI2.Lamp.Color = [0.00,0.00,0.00];
                   app.elevator2.state = 'r';
                end
                app.F3_1_DownButton.BackgroundColor = [0.96,0.96,0.96];
                app.button2.F3_2_DownButton.BackgroundColor = [0.96,0.96,0.96];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Floor3Panel = uipanel(app.UIFigure);
            app.Floor3Panel.Title = 'Floor 3';
            app.F3_1 = uipanel(app.Floor3Panel);
            app.F3_1.Title = '#1';
            app.F3_1_DownButton = uibutton(app.F3_1, 'push');
            app.F3_1_DownButton.ButtonPushedFcn = createCallbackFcn(app, @F3_1_DownButtonPushed, true);
            app.F3_1_DownButton.Text = 'Down';
            app.F3_1_EditField = uieditfield(app.F3_1, 'numeric');
            app.F3_1_EditField.Limits = [1 3];
            app.F3_1_EditField.Value = 1;
            app.doorEditField_3Label = uilabel(app.Floor3Panel);
            app.doorEditField_3Label.Text = 'door';
            app.F3_1_doorEditField = uieditfield(app.Floor3Panel, 'text');
            app.F3_1_doorEditField.Editable = 'off';
            app.Floor2Panel = uipanel(app.UIFigure);
            app.Floor2Panel.Title = 'Floor 2';
            app.F2_1 = uipanel(app.Floor2Panel);
            app.F2_1.Title = '#1';
            app.F2_1_DownButton = uibutton(app.F2_1, 'push');
            app.F2_1_DownButton.ButtonPushedFcn = createCallbackFcn(app, @F2_1_DownButtonPushed, true);
            app.F2_1_DownButton.Text = 'Down';
            app.F2_1_EditField = uieditfield(app.F2_1, 'numeric');
            app.F2_1_EditField.Limits = [1 3];
            app.F2_1_EditField.Value = 1;
            app.F2_1_UpButton = uibutton(app.F2_1, 'push');
            app.F2_1_UpButton.ButtonPushedFcn = createCallbackFcn(app, @F2_1_UpButtonPushed, true);
            app.F2_1_UpButton.Text = 'Up';
            app.doorEditField_4Label = uilabel(app.Floor2Panel);
            app.doorEditField_4Label.Text = 'door';
            app.F2_1_doorEditField = uieditfield(app.Floor2Panel, 'text');
            app.F2_1_doorEditField.Editable = 'off';
            app.Floor1Panel = uipanel(app.UIFigure);
            app.Floor1Panel.Title = 'Floor 1';
            app.F1_1 = uipanel(app.Floor1Panel);
            app.F1_1.Title = '#1';
            app.F1_1_UpButton = uibutton(app.F1_1, 'push');
            app.F1_1_UpButton.ButtonPushedFcn = createCallbackFcn(app, @F1_1_UpButtonPushed, true);
            app.F1_1_UpButton.Text = 'Up';
            app.F1_1_EditField = uieditfield(app.F1_1, 'numeric');
            app.F1_1_EditField.Limits = [1 3];
            app.F1_1_EditField.Value = 1;
            app.doorEditField_5Label = uilabel(app.Floor1Panel);
            app.doorEditField_5Label.Text = 'door';
            app.F1_1_doorEditField = uieditfield(app.Floor1Panel, 'text');
            app.F1_1_doorEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ButtonPanelApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end