classdef ButtonPanelApp2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Floor3Panel           matlab.ui.container.Panel
        F3                    matlab.ui.container.Panel
        F3_2_DownButton       matlab.ui.control.Button
        F3_2_EditField        matlab.ui.control.NumericEditField
        doorEditField_3Label  matlab.ui.control.Label
        F3_2_doorEditField    matlab.ui.control.EditField
        Floor2Panel           matlab.ui.container.Panel
        F2                    matlab.ui.container.Panel
        F2_2_DownButton       matlab.ui.control.Button
        F2_2_EditField        matlab.ui.control.NumericEditField
        F2_2_UpButton         matlab.ui.control.Button
        doorEditField_4Label  matlab.ui.control.Label
        F2_2_doorEditField    matlab.ui.control.EditField
        Floor1Panel           matlab.ui.container.Panel
        F1                    matlab.ui.container.Panel
        F1_2_UpButton         matlab.ui.control.Button
        F1_2_EditField        matlab.ui.control.NumericEditField
        doorEditField_5Label  matlab.ui.control.Label
        F1_2_doorEditField    matlab.ui.control.EditField
    end

    
    properties (Access = public)
        elevator2 % elevator#2
        elevator1 % elevator#1
        button1   % buttonpanel#1
        eleUI1
        eleUI2
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                      
            app.F1_2_doorEditField.Value = 'close';
            app.F2_2_doorEditField.Value = 'close';
            app.F3_2_doorEditField.Value = 'close';
                  
            app.F1_2_EditField.Value = 3;
            app.F2_2_EditField.Value = 3;
            app.F3_2_EditField.Value = 3;
        end

        % Button pushed function: F3_2_DownButton
        function F3_2_DownButtonPushed(app, event)
            app.F3_2_DownButton.BackgroundColor = [0.39,0.83,0.07];
            app.button1.F3_1_DownButton.BackgroundColor = [0.39,0.83,0.07];
            if app.elevator2.level == 3
                app.elevator2.openDoor(32);
            else
                app.elevator2.state = 'move';
               app.elevator2.ButtonFor2(3,32); 
               app.elevator2.state = 'stop';
            end 
            app.F3_2_DownButton.BackgroundColor = [0.96,0.96,0.96];
            app.button1.F3_1_DownButton.BackgroundColor = [0.96,0.96,0.96];
        end

        % Button pushed function: F2_2_UpButton
        function F2_2_UpButtonPushed(app, event)
            app.F2_2_UpButton.BackgroundColor = [0.39,0.83,0.07]; 
            app.button1.F2_1_UpButton.BackgroundColor = [0.39,0.83,0.07];
            if app.elevator2.level == 2
                app.elevator2.openDoor(22);
            else
                app.elevator2.state = 'move';
               app.elevator2.ButtonFor2(2,22); 
               app.elevator2.state = 'stop';
            end 
            app.F2_2_UpButton.BackgroundColor = [0.96,0.96,0.96];
            app.button1.F2_1_UpButton.BackgroundColor = [0.96,0.96,0.96];
        end

        % Button pushed function: F2_2_DownButton
        function F2_2_DownButtonPushed(app, event)
            app.F2_2_DownButton.BackgroundColor = [0.39,0.83,0.07]; 
            app.button1.F2_1_DownButton.BackgroundColor = [0.39,0.83,0.07];
            if app.elevator2.level == 2
                app.elevator2.openDoor(22);
            else
                app.elevator2.state = 'move';
               app.elevator2.ButtonFor2(2,22); 
               app.elevator2.state = 'stop';
            end 
            app.F2_2_DownButton.BackgroundColor = [0.96,0.96,0.96];
            app.button1.F2_1_DownButton.BackgroundColor = [0.96,0.96,0.96];
        end

        % Button pushed function: F1_2_UpButton
        function F1_2_UpButtonPushed(app, event)
            app.F1_2_UpButton.BackgroundColor = [0.39,0.83,0.07]; 
            app.button1.F1_1_UpButton.BackgroundColor = [0.39,0.83,0.07];
            if app.elevator2.level == 1
                app.elevator2.openDoor(12);
            else
                app.elevator2.state = 'move';
               app.elevator2.ButtonFor2(1,12); 
               app.elevator2.state = 'stop';
            end 
            app.F1_2_UpButton.BackgroundColor = [0.96,0.96,0.96];
            app.button1.F1_1_UpButton.BackgroundColor = [0.96,0.96,0.96];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Floor3Panel = uipanel(app.UIFigure);
            app.Floor3Panel.Title = 'Floor 3';
            app.F3 = uipanel(app.Floor3Panel);
            app.F3.Title = '#2';
            app.F3_2_DownButton = uibutton(app.F3, 'push');
            app.F3_2_DownButton.ButtonPushedFcn = createCallbackFcn(app, @F3_2_DownButtonPushed, true);
            app.F3_2_DownButton.Text = 'Down';
            app.F3_2_EditField = uieditfield(app.F3, 'numeric');
            app.F3_2_EditField.Limits = [1 3];
            app.F3_2_EditField.Value = 1;
            app.doorEditField_3Label = uilabel(app.Floor3Panel);
            app.doorEditField_3Label.Text = 'door';
            app.F3_2_doorEditField = uieditfield(app.Floor3Panel, 'text');
            app.F3_2_doorEditField.Editable = 'off';
            app.Floor2Panel = uipanel(app.UIFigure);
            app.Floor2Panel.Title = 'Floor 2';
            app.F2 = uipanel(app.Floor2Panel);
            app.F2.Title = '#2';
            app.F2_2_DownButton = uibutton(app.F2, 'push');
            app.F2_2_DownButton.ButtonPushedFcn = createCallbackFcn(app, @F2_2_DownButtonPushed, true);
            app.F2_2_DownButton.Text = 'Down';
            app.F2_2_EditField = uieditfield(app.F2, 'numeric');
            app.F2_2_EditField.Limits = [1 3];
            app.F2_2_EditField.Value = 1;
            app.F2_2_UpButton = uibutton(app.F2, 'push');
            app.F2_2_UpButton.ButtonPushedFcn = createCallbackFcn(app, @F2_2_UpButtonPushed, true);
            app.F2_2_UpButton.Text = 'Up';
            app.doorEditField_4Label = uilabel(app.Floor2Panel);
            app.doorEditField_4Label.Text = 'door';
            app.F2_2_doorEditField = uieditfield(app.Floor2Panel, 'text');
            app.F2_2_doorEditField.Editable = 'off';
            app.Floor1Panel = uipanel(app.UIFigure);
            app.Floor1Panel.Title = 'Floor 1';
            app.F1 = uipanel(app.Floor1Panel);
            app.F1.Title = '#2';
            app.F1_2_UpButton = uibutton(app.F1, 'push');
            app.F1_2_UpButton.ButtonPushedFcn = createCallbackFcn(app, @F1_2_UpButtonPushed, true);
            app.F1_2_UpButton.Text = 'Up';
            app.F1_2_EditField = uieditfield(app.F1, 'numeric');
            app.F1_2_EditField.Limits = [1 3];
            app.F1_2_EditField.Value = 1;
            app.doorEditField_5Label = uilabel(app.Floor1Panel);
            app.doorEditField_5Label.Text = 'door';
            app.F1_2_doorEditField = uieditfield(app.Floor1Panel, 'text');
            app.F1_2_doorEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ButtonPanelApp2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end