classdef ElevatorUI1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Button_1            matlab.ui.control.Button
        Button_2            matlab.ui.control.Button
        Button_3            matlab.ui.control.Button
        Label               matlab.ui.control.Label
        OpenButton          matlab.ui.control.Button
        CloseButton         matlab.ui.control.Button
        doorEditFieldLabel  matlab.ui.control.Label
        doorEditField       matlab.ui.control.EditField
        EditField           matlab.ui.control.NumericEditField
        Lamp                matlab.ui.control.Lamp
    end

    
    properties (Access = public)
        elevator % elevator#1
        button1
        button2
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        
            
            app.EditField.Value = 1;
            app.doorEditField.Value = 'close';
            app.CloseButton.Enable = 0;
            

        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            app.OpenButton.BackgroundColor = [0.39,0.83,0.07];  
            app.elevator.openDoor(11);  
            app.OpenButton.BackgroundColor = [0.96,0.96,0.96];
        end

        % Button pushed function: Button_1
        function Button_1Pushed(app, event)
            app.elevator.updateTarget(1);
            if app.elevator.target ~= 0
                app.Button_1.BackgroundColor = [0.39,0.83,0.07]; 
            end
            app.elevator.add(1);
            if app.elevator.direction ~= 'u' && app.elevator.direction ~= 'd'
                app.elevator.determinDirection();
                app.elevator.state = 'w';
                if app.elevator.direction == 'u'
                    app.Lamp.Color = [1,0,0];
                    app.elevator.moveup(1);
                    app.Lamp.Color = [0,0,0];
                    app.elevator.direction = 's';
                elseif app.elevator.direction == 'd'
                    app.Lamp.Color = [0,1,0];
                    app.elevator.movedown(1);
                    app.Lamp.Color = [0,0,0];
                    app.elevator.direction = 's';
                else
                    app.elevator.openDoor(app.elevator.retreive_num(1));
                end
                app.elevator.state = 'r';
                
                
            end
            app.Button_1.BackgroundColor = [0.96,0.96,0.96];
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.elevator.updateTarget(2);
            if app.elevator.target ~= 0
                app.Button_2.BackgroundColor = [0.39,0.83,0.07]; 
            end
            app.elevator.add(2);
            if app.elevator.direction ~= 'u' && app.elevator.direction ~= 'd'
                app.elevator.determinDirection();
                app.elevator.state = 'w';
                if app.elevator.direction == 'u'
                    app.Lamp.Color = [1,0,0];
                    app.elevator.moveup(1);
                    app.Lamp.Color = [0,0,0];
                    app.elevator.direction = 's';
                elseif app.elevator.direction == 'd'
                    app.Lamp.Color = [0,1,0];
                    app.elevator.movedown(1);
                    app.Lamp.Color = [0,0,0];
                    app.elevator.direction = 's';
                else
                    app.elevator.openDoor(app.elevator.retreive_num(1));
                end
                app.elevator.state = 'r';
                
                
            end
            app.Button_2.BackgroundColor = [0.96,0.96,0.96];
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.elevator.updateTarget(3);
            if app.elevator.target ~= 0
                app.Button_3.BackgroundColor = [0.39,0.83,0.07]; 
            end
            app.elevator.add(3);
            if app.elevator.direction ~= 'u' && app.elevator.direction ~= 'd'
                app.elevator.determinDirection();
                app.elevator.state = 'w';
                if app.elevator.direction == 'u'
                    app.Lamp.Color = [1,0,0];
                    app.elevator.moveup(1);
                    app.Lamp.Color = [0,0,0];
                    app.elevator.direction = 's';
                elseif app.elevator.direction == 'd'
                    app.Lamp.Color = [0,1,0];
                    app.elevator.movedown(1);
                    app.Lamp.Color = [0,0,0];
                    app.elevator.direction = 's';
                else
                    app.elevator.openDoor(app.elevator.retreive_num(1));
                end
                app.elevator.state = 'r';
                
                
            end
            app.Button_3.BackgroundColor = [0.96,0.96,0.96];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Button_1 = uibutton(app.UIFigure, 'push');
            app.Button_1.ButtonPushedFcn = createCallbackFcn(app, @Button_1Pushed, true);
            app.Button_1.Text = '1';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '2';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '3';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '#1';
            app.OpenButton = uibutton(app.UIFigure, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.Text = 'Close';
            app.doorEditFieldLabel = uilabel(app.UIFigure);
            app.doorEditFieldLabel.Text = 'door';
            app.doorEditField = uieditfield(app.UIFigure, 'text');
            app.doorEditField.Editable = 'off';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.Limits = [1 3];
            app.EditField.Value = 1;
            app.Lamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ElevatorUI1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end