classdef ButtonPanelApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Floor3Panel           matlab.ui.container.Panel
        F3_1                  matlab.ui.container.Panel
        F3_1_DownButton       matlab.ui.control.Button
        F3_1_EditField        matlab.ui.control.NumericEditField
        doorEditField_3Label  matlab.ui.control.Label
        F3_1_doorEditField    matlab.ui.control.EditField
        Floor2Panel           matlab.ui.container.Panel
        F2_1                  matlab.ui.container.Panel
        F2_1_DownButton       matlab.ui.control.Button
        F2_1_EditField        matlab.ui.control.NumericEditField
        F2_1_UpButton         matlab.ui.control.Button
        doorEditField_4Label  matlab.ui.control.Label
        F2_1_doorEditField    matlab.ui.control.EditField
        Floor1Panel           matlab.ui.container.Panel
        F1_1                  matlab.ui.container.Panel
        F1_1_UpButton         matlab.ui.control.Button
        F1_1_EditField        matlab.ui.control.NumericEditField
        doorEditField_5Label  matlab.ui.control.Label
        F1_1_doorEditField    matlab.ui.control.EditField
    end

    
    properties (Access = public)
        elevator;
        button2;
        ct;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.F1_1_doorEditField.Value = 'close';
            app.F2_1_doorEditField.Value = 'close';
            app.F3_1_doorEditField.Value = 'close';

            
            app.F1_1_EditField.Value = 1;
            app.F2_1_EditField.Value = 1;
            app.F3_1_EditField.Value = 1;
        end

        % Button pushed function: F1_1_UpButton
        function F1_1_UpButtonPushed(app, event)
            app.F1_1_UpButton.BackgroundColor = [0.39,0.83,0.07];
            app.button2.F1_2_UpButton.BackgroundColor = [0.39,0.83,0.07];
            app.elevator.trigger(1,1,'u',1);
        end

        % Button pushed function: F2_1_UpButton
        function F2_1_UpButtonPushed(app, event)
            app.F2_1_UpButton.BackgroundColor = [0.39,0.83,0.07];
            app.button2.F2_2_UpButton.BackgroundColor = [0.39,0.83,0.07];     
            app.elevator.trigger(1,2,'u',2);
        end

        % Button pushed function: F2_1_DownButton
        function F2_1_DownButtonPushed(app, event)
            app.F2_1_DownButton.BackgroundColor = [0.39,0.83,0.07];
            app.button2.F2_2_DownButton.BackgroundColor = [0.39,0.83,0.07];
            app.elevator.trigger(1,2,'d',3);
        end

        % Button pushed function: F3_1_DownButton
        function F3_1_DownButtonPushed(app, event)
            app.F3_1_DownButton.BackgroundColor = [0.39,0.83,0.07];
            app.button2.F3_2_DownButton.BackgroundColor = [0.39,0.83,0.07];
            app.elevator.trigger(1,3,'d',4);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Floor3Panel = uipanel(app.UIFigure);
            app.Floor3Panel.Title = 'Floor 3';
            app.F3_1 = uipanel(app.Floor3Panel);
            app.F3_1.Title = '#1';
            app.F3_1_DownButton = uibutton(app.F3_1, 'push');
            app.F3_1_DownButton.ButtonPushedFcn = createCallbackFcn(app, @F3_1_DownButtonPushed, true);
            app.F3_1_DownButton.Text = 'Down';
            app.F3_1_EditField = uieditfield(app.F3_1, 'numeric');
            app.F3_1_EditField.Limits = [1 3];
            app.F3_1_EditField.Value = 1;
            app.doorEditField_3Label = uilabel(app.Floor3Panel);
            app.doorEditField_3Label.Text = 'door';
            app.F3_1_doorEditField = uieditfield(app.Floor3Panel, 'text');
            app.F3_1_doorEditField.Editable = 'off';
            app.Floor2Panel = uipanel(app.UIFigure);
            app.Floor2Panel.Title = 'Floor 2';
            app.F2_1 = uipanel(app.Floor2Panel);
            app.F2_1.Title = '#1';
            app.F2_1_DownButton = uibutton(app.F2_1, 'push');
            app.F2_1_DownButton.ButtonPushedFcn = createCallbackFcn(app, @F2_1_DownButtonPushed, true);
            app.F2_1_DownButton.Text = 'Down';
            app.F2_1_EditField = uieditfield(app.F2_1, 'numeric');
            app.F2_1_EditField.Limits = [1 3];
            app.F2_1_EditField.Value = 1;
            app.F2_1_UpButton = uibutton(app.F2_1, 'push');
            app.F2_1_UpButton.ButtonPushedFcn = createCallbackFcn(app, @F2_1_UpButtonPushed, true);
            app.F2_1_UpButton.Text = 'Up';
            app.doorEditField_4Label = uilabel(app.Floor2Panel);
            app.doorEditField_4Label.Text = 'door';
            app.F2_1_doorEditField = uieditfield(app.Floor2Panel, 'text');
            app.F2_1_doorEditField.Editable = 'off';
            app.Floor1Panel = uipanel(app.UIFigure);
            app.Floor1Panel.Title = 'Floor 1';
            app.F1_1 = uipanel(app.Floor1Panel);
            app.F1_1.Title = '#1';
            app.F1_1_UpButton = uibutton(app.F1_1, 'push');
            app.F1_1_UpButton.ButtonPushedFcn = createCallbackFcn(app, @F1_1_UpButtonPushed, true);
            app.F1_1_UpButton.Text = 'Up';
            app.F1_1_EditField = uieditfield(app.F1_1, 'numeric');
            app.F1_1_EditField.Limits = [1 3];
            app.F1_1_EditField.Value = 1;
            app.doorEditField_5Label = uilabel(app.Floor1Panel);
            app.doorEditField_5Label.Text = 'door';
            app.F1_1_doorEditField = uieditfield(app.Floor1Panel, 'text');
            app.F1_1_doorEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ButtonPanelApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end