classdef buttonUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Floor3Panel            matlab.ui.container.Panel
        F3                     matlab.ui.container.Panel
        F3_DownButton          matlab.ui.control.Button
        F3_dt1_num             matlab.ui.control.NumericEditField
        F3_dt2_num             matlab.ui.control.NumericEditField
        doorEditFieldLabel     matlab.ui.control.Label
        F3_dt1_door            matlab.ui.control.NumericEditField
        doorEditField_7Label   matlab.ui.control.Label
        F3_dt2_door            matlab.ui.control.NumericEditField
        Floor2Panel            matlab.ui.container.Panel
        F3_2                   matlab.ui.container.Panel
        F2_DownButton          matlab.ui.control.Button
        F2_dt1_num             matlab.ui.control.NumericEditField
        F2_dt2_num             matlab.ui.control.NumericEditField
        F2_UpButton            matlab.ui.control.Button
        doorEditField_9Label   matlab.ui.control.Label
        F2_dt2_door            matlab.ui.control.NumericEditField
        doorEditField_8Label   matlab.ui.control.Label
        F2_dt1_door            matlab.ui.control.NumericEditField
        Floor1Panel            matlab.ui.container.Panel
        F3_3                   matlab.ui.container.Panel
        F1_UpButton            matlab.ui.control.Button
        F1_dt1_num             matlab.ui.control.NumericEditField
        F1_dt2_num             matlab.ui.control.NumericEditField
        doorEditField_10Label  matlab.ui.control.Label
        F1_dt2_door            matlab.ui.control.NumericEditField
        doorEditField_11Label  matlab.ui.control.Label
        F1_dt1_door            matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        contrler;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: F3_DownButton
        function F3_DownButtonPushed(app, event)
            if  ~isequal(app.F3_DownButton.BackgroundColor , [0,1,0])
                app.contrler.target = app.contrler.target + 1;
                app.contrler.F3_signal = 1;
                app.F3_DownButton.BackgroundColor = [0,1,0];
            end
        end

        % Button pushed function: F2_UpButton
        function F2_UpButtonPushed(app, event)
            if  ~isequal(app.F2_UpButton.BackgroundColor , [0,1,0])
                app.contrler.target = app.contrler.target + 1;
                app.contrler.F2_up_signal = 1;
                app.F2_UpButton.BackgroundColor = [0,1,0];            
            end
        end

        % Button pushed function: F2_DownButton
        function F2_DownButtonPushed(app, event)
            if  ~isequal(app.F2_DownButton.BackgroundColor , [0,1,0])
                app.contrler.target = app.contrler.target + 1;
                app.contrler.F2_down_signal = 1;
                app.F2_DownButton.BackgroundColor = [0,1,0];    
            end
        end

        % Button pushed function: F1_UpButton
        function F1_UpButtonPushed(app, event)
            if  ~isequal(app.F1_UpButton.BackgroundColor , [0,1,0])
                app.contrler.target = app.contrler.target + 1;
                app.contrler.F1_signal = 1;
                app.F1_UpButton.BackgroundColor = [0,1,0];       
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Floor3Panel = uipanel(app.UIFigure);
            app.Floor3Panel.Title = 'Floor 3';
            app.F3 = uipanel(app.Floor3Panel);
            app.F3_DownButton = uibutton(app.F3, 'push');
            app.F3_DownButton.ButtonPushedFcn = createCallbackFcn(app, @F3_DownButtonPushed, true);
            app.F3_DownButton.Text = 'Down';
            app.F3_dt1_num = uieditfield(app.F3, 'numeric');
            app.F3_dt1_num.Limits = [1 3];
            app.F3_dt1_num.Value = 1;
            app.F3_dt2_num = uieditfield(app.F3, 'numeric');
            app.F3_dt2_num.Limits = [1 3];
            app.F3_dt2_num.Value = 1;
            app.doorEditFieldLabel = uilabel(app.Floor3Panel);
            app.doorEditFieldLabel.Text = 'door';
            app.F3_dt1_door = uieditfield(app.Floor3Panel, 'numeric');
            app.doorEditField_7Label = uilabel(app.Floor3Panel);
            app.doorEditField_7Label.Text = 'door';
            app.F3_dt2_door = uieditfield(app.Floor3Panel, 'numeric');
            app.Floor2Panel = uipanel(app.UIFigure);
            app.Floor2Panel.Title = 'Floor 2';
            app.F3_2 = uipanel(app.Floor2Panel);
            app.F2_DownButton = uibutton(app.F3_2, 'push');
            app.F2_DownButton.ButtonPushedFcn = createCallbackFcn(app, @F2_DownButtonPushed, true);
            app.F2_DownButton.Text = 'Down';
            app.F2_dt1_num = uieditfield(app.F3_2, 'numeric');
            app.F2_dt1_num.Limits = [1 3];
            app.F2_dt1_num.Value = 1;
            app.F2_dt2_num = uieditfield(app.F3_2, 'numeric');
            app.F2_dt2_num.Limits = [1 3];
            app.F2_dt2_num.Value = 1;
            app.F2_UpButton = uibutton(app.F3_2, 'push');
            app.F2_UpButton.ButtonPushedFcn = createCallbackFcn(app, @F2_UpButtonPushed, true);
            app.F2_UpButton.Text = 'Up';
            app.doorEditField_9Label = uilabel(app.Floor2Panel);
            app.doorEditField_9Label.Text = 'door';
            app.F2_dt2_door = uieditfield(app.Floor2Panel, 'numeric');
            app.doorEditField_8Label = uilabel(app.Floor2Panel);
            app.doorEditField_8Label.Text = 'door';
            app.F2_dt1_door = uieditfield(app.Floor2Panel, 'numeric');
            app.Floor1Panel = uipanel(app.UIFigure);
            app.Floor1Panel.Title = 'Floor 1';
            app.F3_3 = uipanel(app.Floor1Panel);
            app.F1_UpButton = uibutton(app.F3_3, 'push');
            app.F1_UpButton.ButtonPushedFcn = createCallbackFcn(app, @F1_UpButtonPushed, true);
            app.F1_UpButton.Text = 'Up';
            app.F1_dt1_num = uieditfield(app.F3_3, 'numeric');
            app.F1_dt1_num.Limits = [1 3];
            app.F1_dt1_num.Value = 1;
            app.F1_dt2_num = uieditfield(app.F3_3, 'numeric');
            app.F1_dt2_num.Limits = [1 3];
            app.F1_dt2_num.Value = 1;
            app.doorEditField_10Label = uilabel(app.Floor1Panel);
            app.doorEditField_10Label.Text = 'door';
            app.F1_dt2_door = uieditfield(app.Floor1Panel, 'numeric');
            app.doorEditField_11Label = uilabel(app.Floor1Panel);
            app.doorEditField_11Label.Text = 'door';
            app.F1_dt1_door = uieditfield(app.Floor1Panel, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = buttonUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end