classdef elevatorUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Panel                 matlab.ui.container.Panel
        Button                matlab.ui.control.Button
        Button_2              matlab.ui.control.Button
        Button_3              matlab.ui.control.Button
        Label                 matlab.ui.control.Label
        OpenButton            matlab.ui.control.Button
        CloseButton           matlab.ui.control.Button
        dt1_num               matlab.ui.control.NumericEditField
        doorEditFieldLabel    matlab.ui.control.Label
        dt1_door              matlab.ui.control.NumericEditField
        Panel_2               matlab.ui.container.Panel
        Button_4              matlab.ui.control.Button
        Button_5              matlab.ui.control.Button
        Button_6              matlab.ui.control.Button
        Label_2               matlab.ui.control.Label
        OpenButton_2          matlab.ui.control.Button
        CloseButton_2         matlab.ui.control.Button
        dt2_num               matlab.ui.control.NumericEditField
        doorEditFieldLabel_2  matlab.ui.control.Label
        dt2_door              matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        contrler;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            if isequal(app.contrler.model.dt1.Position, [80,0,59,100])
                app.contrler.opendoor(1,1);    
            elseif isequal(app.contrler.model.dt1.Position, [80,300,59,100])
                app.contrler.opendoor(1,2);    
            elseif isequal(app.contrler.model.dt1.Position, [80,600,59,100])
                app.contrler.opendoor(1,3);                    
            end
        end

        % Button pushed function: OpenButton_2
        function OpenButton_2Pushed(app, event)
            if isequal(app.contrler.model.dt2.Position, [265,0,59,100])
                app.contrler.opendoor(2,1);    
            elseif isequal(app.contrler.model.dt2.Position, [265,300,59,100])
                app.contrler.opendoor(2,2);    
            elseif isequal(app.contrler.model.dt2.Position, [265,600,59,100])
                app.contrler.opendoor(2,3);                    
            end         
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            if ~isequal(app.Button.BackgroundColor, [0,1,0])
                if (app.contrler.dt1_direction == 0 && app.contrler.dt1s1 == 0) || (app.contrler.dt1_direction == 2 && app.contrler.dt1s1 == 0)
                    app.contrler.dt1s1 = 1;
                    fprintf("%d\n",app.contrler.dt1s1);
                    app.contrler.target2 = app.contrler.target2 + 1;
                    app.Button.BackgroundColor = [0,1,0];
                end
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            if ~isequal(app.Button_2.BackgroundColor, [0,1,0])
                if (app.contrler.dt1_direction == 0 && app.contrler.dt1s2 == 0) || (app.contrler.dt1_direction == 2 && app.contrler.dt1s2 == 0 && app.contrler.model.dt1.Position(2) >= 301) || (app.contrler.dt1_direction == 1 && app.contrler.dt1s2 == 0 && app.contrler.model.dt1.Position(2) <= 299)
                    app.contrler.dt1s2 = 1;
                    app.contrler.target2 = app.contrler.target2 + 1;
                    app.Button_2.BackgroundColor = [0,1,0];
                end
                %if (app.contrler.dt1_direction == 0 && app.contrler.F2_up_signal == 0) || (app.contrler.dt1_direction == 1 && app.contrler.F2_up_signal == 0)
                %    app.contrler.dt1s2 = 1;
                %    app.contrler.target2 = app.contrler.target2 + 1;
                %    app.Button_2.BackgroundColor = [0,1,0];                    
                %end
            end            
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            if ~isequal(app.Button_3.BackgroundColor, [0,1,0])
                if (app.contrler.dt1_direction == 0 && app.contrler.dt1s3 == 0) || (app.contrler.dt1_direction == 1 && app.contrler.dt1s3 == 0)
                    app.contrler.dt1s3 = 1;
                    app.contrler.target2 = app.contrler.target2 + 1;
                    app.Button_3.BackgroundColor = [0,1,0];
                end
            end            
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            if ~isequal(app.Button_4.BackgroundColor, [0,1,0])
                if (app.contrler.dt2_direction == 0 && app.contrler.dt2s1 == 0) || (app.contrler.dt2_direction == 2 && app.contrler.dt2s1 == 0)
                    app.contrler.dt2s1 = 1;
                    app.contrler.target2 = app.contrler.target2 + 1;
                    app.Button_4.BackgroundColor = [0,1,0];
                end
            end            
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            if ~isequal(app.Button_5.BackgroundColor, [0,1,0])
                if (app.contrler.dt2_direction == 0 && app.contrler.dt2s2 == 0) || (app.contrler.dt2_direction == 2 && app.contrler.dt2s2 == 0 && app.contrler.model.dt2.Position(2) >= 301) || (app.contrler.dt2_direction == 1 && app.contrler.dt2s2 == 0 && app.contrler.model.dt1.Position(2) <= 299)
                    app.contrler.dt2s2 = 1;
                    app.contrler.target2 = app.contrler.target2 + 1;
                    app.Button_5.BackgroundColor = [0,1,0];
                end
                %if (app.contrler.dt2_direction == 0 && app.contrler.F2_up_signal == 0) || (app.contrler.dt2_direction == 1 && app.contrler.F2_up_signal == 0)
                %    app.contrler.F2_up_signal = 1;
                %    app.contrler.target = app.contrler.target + 1;
                %    app.Button_5.BackgroundColor = [0,1,0];                    
               % end
            end                    
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            if ~isequal(app.Button_6.BackgroundColor, [0,1,0])
                if (app.contrler.dt2_direction == 0 && app.contrler.dt2s3 == 0) || (app.contrler.dt2_direction == 1 && app.contrler.dt2s3 == 0)
                    app.contrler.dt2s3 = 1;
                    app.contrler.target2 = app.contrler.target2 + 1;
                    app.Button_6.BackgroundColor = [0,1,0];
                end
            end              
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Panel = uipanel(app.UIFigure);
            app.Button = uibutton(app.Panel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '1';
            app.Button_2 = uibutton(app.Panel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '2';
            app.Button_3 = uibutton(app.Panel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '3';
            app.Label = uilabel(app.Panel);
            app.Label.Text = '#1';
            app.OpenButton = uibutton(app.Panel, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.CloseButton = uibutton(app.Panel, 'push');
            app.CloseButton.Text = 'Close';
            app.dt1_num = uieditfield(app.Panel, 'numeric');
            app.dt1_num.Limits = [1 3];
            app.dt1_num.Value = 1;
            app.doorEditFieldLabel = uilabel(app.Panel);
            app.doorEditFieldLabel.Text = 'door';
            app.dt1_door = uieditfield(app.Panel, 'numeric');
            app.Panel_2 = uipanel(app.UIFigure);
            app.Button_4 = uibutton(app.Panel_2, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '1';
            app.Button_5 = uibutton(app.Panel_2, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '2';
            app.Button_6 = uibutton(app.Panel_2, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '3';
            app.Label_2 = uilabel(app.Panel_2);
            app.Label_2.Text = '#2';
            app.OpenButton_2 = uibutton(app.Panel_2, 'push');
            app.OpenButton_2.ButtonPushedFcn = createCallbackFcn(app, @OpenButton_2Pushed, true);
            app.OpenButton_2.Text = 'Open';
            app.CloseButton_2 = uibutton(app.Panel_2, 'push');
            app.CloseButton_2.Text = 'Close';
            app.dt2_num = uieditfield(app.Panel_2, 'numeric');
            app.dt2_num.Limits = [1 3];
            app.dt2_num.Value = 1;
            app.doorEditFieldLabel_2 = uilabel(app.Panel_2);
            app.doorEditFieldLabel_2.Text = 'door';
            app.dt2_door = uieditfield(app.Panel_2, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = elevatorUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end