classdef buttonUI2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Floor2Panel           matlab.ui.container.Panel
        F3                    matlab.ui.container.Panel
        F2_DownButton         matlab.ui.control.Button
        F2_dt1_num            matlab.ui.control.NumericEditField
        F2_dt2_num            matlab.ui.control.NumericEditField
        F2_UpButton           matlab.ui.control.Button
        doorEditField_9Label  matlab.ui.control.Label
        F2_dt2_door           matlab.ui.control.NumericEditField
        doorEditField_8Label  matlab.ui.control.Label
        F2_dt1_door           matlab.ui.control.NumericEditField
        m11                   matlab.ui.control.TextArea
        m12                   matlab.ui.control.TextArea
        m21                   matlab.ui.control.TextArea
        m22                   matlab.ui.control.TextArea
    end

    
    properties (Access = public)
        contrler;
    end
    

    methods (Access = private)

        % Button pushed function: F2_DownButton
        function F2_DownButtonPushed(app, event)
            if  ~isequal(app.F2_DownButton.BackgroundColor , [0,1,0])
                app.contrler.target = app.contrler.target + 1;
                app.contrler.F2_down_signal = 1;
                app.F2_DownButton.BackgroundColor = [0,1,0];    
            end            
        end

        % Button pushed function: F2_UpButton
        function F2_UpButtonPushed(app, event)
            if  ~isequal(app.F2_UpButton.BackgroundColor , [0,1,0])
                app.contrler.target = app.contrler.target + 1;
                app.contrler.F2_up_signal = 1;
                app.F2_UpButton.BackgroundColor = [0,1,0];            
            end            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Floor2Panel = uipanel(app.UIFigure);
            app.Floor2Panel.Title = 'Floor 2';
            app.F3 = uipanel(app.Floor2Panel);
            app.F2_DownButton = uibutton(app.F3, 'push');
            app.F2_DownButton.ButtonPushedFcn = createCallbackFcn(app, @F2_DownButtonPushed, true);
            app.F2_DownButton.Text = 'Down';
            app.F2_dt1_num = uieditfield(app.F3, 'numeric');
            app.F2_dt1_num.Limits = [1 3];
            app.F2_dt1_num.Value = 1;
            app.F2_dt2_num = uieditfield(app.F3, 'numeric');
            app.F2_dt2_num.Limits = [1 3];
            app.F2_dt2_num.Value = 1;
            app.F2_UpButton = uibutton(app.F3, 'push');
            app.F2_UpButton.ButtonPushedFcn = createCallbackFcn(app, @F2_UpButtonPushed, true);
            app.F2_UpButton.Text = 'Up';
            app.doorEditField_9Label = uilabel(app.Floor2Panel);
            app.doorEditField_9Label.Text = 'door';
            app.F2_dt2_door = uieditfield(app.Floor2Panel, 'numeric');
            app.doorEditField_8Label = uilabel(app.Floor2Panel);
            app.doorEditField_8Label.Text = 'door';
            app.F2_dt1_door = uieditfield(app.Floor2Panel, 'numeric');
            app.m11 = uitextarea(app.UIFigure);
            app.m12 = uitextarea(app.UIFigure);
            app.m21 = uitextarea(app.UIFigure);
            app.m22 = uitextarea(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = buttonUI2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end