classdef elevatorUI2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Panel               matlab.ui.container.Panel
        Button_4            matlab.ui.control.Button
        Button_5            matlab.ui.control.Button
        Button_6            matlab.ui.control.Button
        Label               matlab.ui.control.Label
        OpenButton_2        matlab.ui.control.Button
        CloseButton         matlab.ui.control.Button
        dt2_num             matlab.ui.control.NumericEditField
        doorEditFieldLabel  matlab.ui.control.Label
        dt2_door            matlab.ui.control.NumericEditField
        HelpButton          matlab.ui.control.Button
    end

    
    properties (Access = public)
        contrler;
    end
    

    methods (Access = private)

        % Button pushed function: OpenButton_2
        function OpenButton_2Pushed(app, event)
            if isequal(app.contrler.model.dt2.Position, [265,0,59,100])
                app.contrler.opendoor2(1);    
            elseif isequal(app.contrler.model.dt2.Position, [265,300,59,100])
                app.contrler.opendoor2(2);    
            elseif isequal(app.contrler.model.dt2.Position, [265,600,59,100])
                app.contrler.opendoor2(3);                    
            end 
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            if ~isequal(app.Button_4.BackgroundColor, [0,1,0])
                if (app.contrler.dt2_direction == 0 && app.contrler.dt2s1 == 0) || (app.contrler.dt2_direction == 2 && app.contrler.dt2s1 == 0)
                    app.contrler.dt2s1 = 1;
                    app.contrler.target2 = app.contrler.target2 + 1;
                    app.Button_4.BackgroundColor = [0,1,0];
                end
            end              
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            if ~isequal(app.Button_5.BackgroundColor, [0,1,0])
                if (app.contrler.dt2_direction == 0 && app.contrler.dt2s2 == 0) || (app.contrler.dt2_direction == 2 && app.contrler.dt2s2 == 0 && app.contrler.model.dt2.Position(2) >= 301) || (app.contrler.dt2_direction == 1 && app.contrler.dt2s2 == 0 && app.contrler.model.dt1.Position(2) <= 299)
                    app.contrler.dt2s2 = 1;
                    app.contrler.target2 = app.contrler.target2 + 1;
                    app.Button_5.BackgroundColor = [0,1,0];
                end
                %if (app.contrler.dt2_direction == 0 && app.contrler.F2_up_signal == 0) || (app.contrler.dt2_direction == 1 && app.contrler.F2_up_signal == 0)
                %    app.contrler.F2_up_signal = 1;
                %    app.contrler.target = app.contrler.target + 1;
                %    app.Button_5.BackgroundColor = [0,1,0];                    
               % end
            end             
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            if ~isequal(app.Button_6.BackgroundColor, [0,1,0])
                if (app.contrler.dt2_direction == 0 && app.contrler.dt2s3 == 0) || (app.contrler.dt2_direction == 1 && app.contrler.dt2s3 == 0)
                    app.contrler.dt2s3 = 1;
                    app.contrler.target2 = app.contrler.target2 + 1;
                    app.Button_6.BackgroundColor = [0,1,0];
                end
            end            
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            stop(app.contrler.tall);
            app.contrler.help = 1;
            app.HelpButton.BackgroundColor = [1,0,0];
            app.contrler.model.ProbelmFixedButton.BackgroundColor = [1,1,0];
            msgbox('Elevator is in danger, Please fix it as soon as possible','Show','help');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Panel = uipanel(app.UIFigure);
            app.Button_4 = uibutton(app.Panel, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '1';
            app.Button_5 = uibutton(app.Panel, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '2';
            app.Button_6 = uibutton(app.Panel, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '3';
            app.Label = uilabel(app.Panel);
            app.Label.Text = '#2';
            app.OpenButton_2 = uibutton(app.Panel, 'push');
            app.OpenButton_2.ButtonPushedFcn = createCallbackFcn(app, @OpenButton_2Pushed, true);
            app.OpenButton_2.Text = 'Open';
            app.CloseButton = uibutton(app.Panel, 'push');
            app.CloseButton.Text = 'Close';
            app.dt2_num = uieditfield(app.Panel, 'numeric');
            app.dt2_num.Limits = [1 3];
            app.dt2_num.Value = 1;
            app.doorEditFieldLabel = uilabel(app.Panel);
            app.doorEditFieldLabel.Text = 'door';
            app.dt2_door = uieditfield(app.Panel, 'numeric');
            app.HelpButton = uibutton(app.Panel, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
        end
    end

    methods (Access = public)

        % Construct app
        function app = elevatorUI2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end