classdef modelUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        dt1                 matlab.ui.control.TextArea
        dt2                 matlab.ui.control.TextArea
        Floor1Label         matlab.ui.control.Label
        Floor2Label         matlab.ui.control.Label
        Floor3Label         matlab.ui.control.Label
        weight1SliderLabel  matlab.ui.control.Label
        dt1_weight          matlab.ui.control.Slider
        weight2SliderLabel  matlab.ui.control.Label
        dt2_weight          matlab.ui.control.Slider
        Label               matlab.ui.control.Label
        Label_2             matlab.ui.control.Label
        ProbelmFixedButton  matlab.ui.control.Button
    end

    
    properties (Access = public)
        contrler;
        count;
        count2;
        lo;
        lo2;
    end


    methods (Access = private)

        % Value changed function: dt1_weight
        function dt1_weightValueChanged(app, event)
            value = app.dt1_weight.Value;

            if value >= 1000
                app.count = 1;
                switch app.dt1.Position(2)                    
                    case 0                        
                        app.contrler.buttonAPP1.F1_dt1_door.Value = 1;
                        app.contrler.buttonAPP1.F1_dt1_door.BackgroundColor = [0,1,0];
                        app.contrler.eleAPP1.dt1_door.Value = 1;
                        app.contrler.model.dt1.BackgroundColor = [0,1,0];
                        app.contrler.eleAPP1.dt1_door.BackgroundColor = [0,1,0];
                        if app.contrler.buttonAPP1.m11.Position(1) == 85
                            app.contrler.buttonAPP1.m11.Position(1) = app.contrler.buttonAPP1.m11.Position(1) - 35;
                        end
                        if app.contrler.buttonAPP1.m12.Position(1) == 140   
                            app.contrler.buttonAPP1.m12.Position(1) = app.contrler.buttonAPP1.m12.Position(1) + 35;
                        end
                        app.lo = 1;
                    case 300
                        app.contrler.buttonAPP2.F2_dt1_door.Value = 1;
                        app.contrler.buttonAPP2.F2_dt1_door.BackgroundColor = [0,1,0];
                        app.contrler.eleAPP1.dt1_door.Value = 1;
                        app.contrler.eleAPP1.dt1_door.BackgroundColor = [0,1,0];
                        app.contrler.model.dt1.BackgroundColor = [0,1,0];
                        if app.contrler.buttonAPP2.m11.Position(1) == 85
                            app.contrler.buttonAPP2.m11.Position(1) = app.contrler.buttonAPP2.m11.Position(1) - 35;
                        end
                        if app.contrler.buttonAPP2.m12.Position(1) == 140   
                            app.contrler.buttonAPP2.m12.Position(1) = app.contrler.buttonAPP2.m12.Position(1) + 35;
                        end
                        app.lo = 2;
                    case 600
                        app.contrler.buttonAPP3.F3_dt1_door.Value = 1;
                        app.contrler.buttonAPP3.F3_dt1_door.BackgroundColor = [0,1,0];
                        app.contrler.eleAPP1.dt1_door.Value = 1;
                        app.contrler.eleAPP1.dt1_door.BackgroundColor = [0,1,0];
                        app.contrler.model.dt1.BackgroundColor = [0,1,0];
                        if app.contrler.buttonAPP3.m11.Position(1) == 85
                            app.contrler.buttonAPP3.m11.Position(1) = app.contrler.buttonAPP3.m11.Position(1) - 35;
                        end
                        if app.contrler.buttonAPP3.m12.Position(1) == 140   
                            app.contrler.buttonAPP3.m12.Position(1) = app.contrler.buttonAPP3.m12.Position(1) + 35;
                        end
                        app.lo = 3;
                end

                stop(app.contrler.tall);   
                msgbox('Elevator 1 is over-weight','Show','error');
            end
            
            if value < 1000 && app.count == 1
                switch app.lo
                    case 1
                        if app.contrler.buttonAPP1.m11.Position(1) == 50
                            app.contrler.buttonAPP1.m11.Position(1) = app.contrler.buttonAPP1.m11.Position(1) + 35;
                        end
                        if app.contrler.buttonAPP1.m12.Position(1) == 175
                            app.contrler.buttonAPP1.m12.Position(1) = app.contrler.buttonAPP1.m12.Position(1) - 35;
                        end
                        app.contrler.model.dt1.BackgroundColor = [0.8,0.8,0.8];
                        app.contrler.buttonAPP1.F1_dt1_door.Value = 0;
                        app.contrler.buttonAPP1.F1_dt1_door.BackgroundColor = [1,1,1];
                        app.contrler.eleAPP1.dt1_door.Value = 0;
                        app.contrler.eleAPP1.dt1_door.BackgroundColor = [1,1,1];
                    case 2
                        if app.contrler.buttonAPP2.m11.Position(1) == 50
                            app.contrler.buttonAPP2.m11.Position(1) = app.contrler.buttonAPP2.m11.Position(1) + 35;
                        end
                        if app.contrler.buttonAPP2.m12.Position(1) == 175
                            app.contrler.buttonAPP2.m12.Position(1) = app.contrler.buttonAPP2.m12.Position(1) - 35;
                        end
                        app.contrler.model.dt1.BackgroundColor = [0.8,0.8,0.8];
                        app.contrler.buttonAPP2.F2_dt1_door.Value = 0;
                        app.contrler.buttonAPP2.F2_dt1_door.BackgroundColor = [1,1,1];
                        app.contrler.eleAPP1.dt1_door.Value = 0;
                        app.contrler.eleAPP1.dt1_door.BackgroundColor = [1,1,1];
                    case 3
                        if app.contrler.buttonAPP3.m11.Position(1) == 50
                            app.contrler.buttonAPP3.m11.Position(1) = app.contrler.buttonAPP3.m11.Position(1) + 35;
                        end
                        if app.contrler.buttonAPP3.m12.Position(1) == 175
                            app.contrler.buttonAPP3.m12.Position(1) = app.contrler.buttonAPP3.m12.Position(1) - 35;
                        end
                        app.contrler.model.dt1.BackgroundColor = [0.8,0.8,0.8];
                        app.contrler.buttonAPP3.F3_dt1_door.Value = 0;
                        app.contrler.buttonAPP3.F3_dt1_door.BackgroundColor = [1,1,1];
                        app.contrler.eleAPP1.dt1_door.Value = 0;
                        app.contrler.eleAPP1.dt1_door.BackgroundColor = [1,1,1];                        
                end
                app.count = 0;
                start(app.contrler.tall);
                
            end
        end

        % Value changed function: dt2_weight
        function dt2_weightValueChanged(app, event)
            value = app.dt2_weight.Value;
            if value >= 1000
                app.count2 = 1;
                switch app.dt2.Position(2)                    
                    case 0                        
                        app.contrler.buttonAPP1.F1_dt2_door.Value = 1;
                        app.contrler.buttonAPP1.F1_dt2_door.BackgroundColor = [0,1,0];
                        app.contrler.eleAPP2.dt2_door.Value = 1;
                        app.contrler.eleAPP2.dt2_door.BackgroundColor = [0,1,0];
                        app.contrler.model.dt2.BackgroundColor = [0,1,0];
                        if app.contrler.buttonAPP1.m21.Position(1) == 615
                            app.contrler.buttonAPP1.m21.Position(1) = app.contrler.buttonAPP1.m21.Position(1) - 35;
                        end
                        if app.contrler.buttonAPP1.m22.Position(1) == 670   
                            app.contrler.buttonAPP1.m22.Position(1) = app.contrler.buttonAPP1.m22.Position(1) + 35;
                        end
                        app.lo2 = 1;
                    case 300
                        app.contrler.buttonAPP2.F2_dt2_door.Value = 1;
                        app.contrler.buttonAPP2.F2_dt2_door.BackgroundColor = [0,1,0];
                        app.contrler.eleAPP2.dt2_door.Value = 1;
                        app.contrler.model.dt2.BackgroundColor = [0,1,0];
                        app.contrler.eleAPP2.dt2_door.BackgroundColor = [0,1,0];
                        if app.contrler.buttonAPP2.m21.Position(1) == 615
                            app.contrler.buttonAPP2.m21.Position(1) = app.contrler.buttonAPP2.m21.Position(1) - 35;
                        end
                        if app.contrler.buttonAPP2.m22.Position(1) == 670   
                            app.contrler.buttonAPP2.m22.Position(1) = app.contrler.buttonAPP2.m22.Position(1) + 35;
                        end
                        app.lo2 = 2;
                    case 600
                        app.contrler.buttonAPP3.F3_dt2_door.Value = 1;
                        app.contrler.buttonAPP3.F3_dt2_door.BackgroundColor = [0,1,0];
                        app.contrler.eleAPP2.dt2_door.Value = 1;
                        app.contrler.eleAPP2.dt2_door.BackgroundColor = [0,1,0];
                        app.contrler.model.dt2.BackgroundColor = [0,1,0];
                        if app.contrler.buttonAPP3.m21.Position(1) == 615
                            app.contrler.buttonAPP3.m21.Position(1) = app.contrler.buttonAPP3.m21.Position(1) - 35;
                        end
                        if app.contrler.buttonAPP3.m22.Position(1) == 670   
                            app.contrler.buttonAPP3.m22.Position(1) = app.contrler.buttonAPP3.m22.Position(1) + 35;
                        end
                        app.lo2 = 3;
                end

                stop(app.contrler.tall);   
                msgbox('Elevator 2 is over-weight','Show','error');
            end
            
            if value < 1000 && app.count2 == 1
                switch app.lo2
                    case 1
                        if app.contrler.buttonAPP1.m21.Position(1) == 580
                            app.contrler.buttonAPP1.m21.Position(1) = app.contrler.buttonAPP1.m21.Position(1) + 35;
                        end
                        if app.contrler.buttonAPP1.m22.Position(1) == 705
                            app.contrler.buttonAPP1.m22.Position(1) = app.contrler.buttonAPP1.m22.Position(1) - 35;
                        end
                        app.contrler.model.dt2.BackgroundColor = [0.8,0.8,0.8];
                        app.contrler.buttonAPP1.F1_dt2_door.Value = 0;
                        app.contrler.buttonAPP1.F1_dt2_door.BackgroundColor = [1,1,1];
                        app.contrler.eleAPP2.dt2_door.Value = 0;
                        app.contrler.eleAPP2.dt2_door.BackgroundColor = [1,1,1];
                    case 2
                        if app.contrler.buttonAPP2.m21.Position(1) == 580
                            app.contrler.buttonAPP2.m21.Position(1) = app.contrler.buttonAPP2.m21.Position(1) + 35;
                        end
                        if app.contrler.buttonAPP2.m22.Position(1) == 705
                            app.contrler.buttonAPP2.m22.Position(1) = app.contrler.buttonAPP2.m22.Position(1) - 35;
                        end
                        app.contrler.model.dt2.BackgroundColor = [0.8,0.8,0.8];
                        app.contrler.buttonAPP2.F2_dt2_door.Value = 0;
                        app.contrler.buttonAPP2.F2_dt2_door.BackgroundColor = [1,1,1];
                        app.contrler.eleAPP2.dt2_door.Value = 0;
                        app.contrler.eleAPP2.dt2_door.BackgroundColor = [1,1,1];
                    case 3
                        if app.contrler.buttonAPP3.m21.Position(1) == 580
                            app.contrler.buttonAPP3.m21.Position(1) = app.contrler.buttonAPP3.m21.Position(1) + 35;
                        end
                        if app.contrler.buttonAPP3.m22.Position(1) == 705
                            app.contrler.buttonAPP3.m22.Position(1) = app.contrler.buttonAPP3.m22.Position(1) - 35;
                        end
                        app.contrler.model.dt2.BackgroundColor = [0.8,0.8,0.8];
                        app.contrler.buttonAPP3.F3_dt2_door.Value = 0;
                        app.contrler.buttonAPP3.F3_dt2_door.BackgroundColor = [1,1,1];
                        app.contrler.eleAPP2.dt2_door.Value = 0;
                        app.contrler.eleAPP2.dt2_door.BackgroundColor = [1,1,1];                  
                end
                app.count2 = 0;
                start(app.contrler.tall);
                
            end
        end

        % Button pushed function: ProbelmFixedButton
        function ProbelmFixedButtonPushed(app, event)
            if app.contrler.help == 1
               start(app.contrler.tall);
               app.contrler.help = 0;
               app.ProbelmFixedButton.BackgroundColor = [0,1,0]
               app.contrler.eleAPP1.HelpButton.BackgroundColor = [1,1,0];
               app.contrler.eleAPP2.HelpButton.BackgroundColor = [1,1,0];
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.dt1 = uitextarea(app.UIFigure);
            app.dt2 = uitextarea(app.UIFigure);
            app.Floor1Label = uilabel(app.UIFigure);
            app.Floor1Label.Text = 'Floor 1';
            app.Floor2Label = uilabel(app.UIFigure);
            app.Floor2Label.Text = 'Floor 2';
            app.Floor3Label = uilabel(app.UIFigure);
            app.Floor3Label.Text = 'Floor 3';
            app.weight1SliderLabel = uilabel(app.UIFigure);
            app.weight1SliderLabel.Text = 'weight（#1）';
            app.dt1_weight = uislider(app.UIFigure);
            app.dt1_weight.Limits = [0 2000];
            app.dt1_weight.MajorTicks = [0 400 800 1200 1600 2000];
            app.dt1_weight.ValueChangedFcn = createCallbackFcn(app, @dt1_weightValueChanged, true);
            app.weight2SliderLabel = uilabel(app.UIFigure);
            app.weight2SliderLabel.Text = 'weight（#2）';
            app.dt2_weight = uislider(app.UIFigure);
            app.dt2_weight.Limits = [0 2000];
            app.dt2_weight.ValueChangedFcn = createCallbackFcn(app, @dt2_weightValueChanged, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '1000';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '1000';
            app.ProbelmFixedButton = uibutton(app.UIFigure, 'push');
            app.ProbelmFixedButton.ButtonPushedFcn = createCallbackFcn(app, @ProbelmFixedButtonPushed, true);
            app.ProbelmFixedButton.Text = 'Probelm Fixed';
        end
    end

    methods (Access = public)

        % Construct app
        function app = modelUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end